classdef TwoDofManipulatorFKCalculationUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        LengthofLink1                   matlab.ui.control.NumericEditField
        LengthofLink1EditFieldLabel     matlab.ui.control.Label
        LengthofLink2                   matlab.ui.control.NumericEditField
        LengthofLink2EditFieldLabel     matlab.ui.control.Label
        EndpointZVal                    matlab.ui.control.NumericEditField
        EndpointZValEditFieldLabel      matlab.ui.control.Label
        EndpointYVal                    matlab.ui.control.NumericEditField
        EndpointYValEditFieldLabel      matlab.ui.control.Label
        EndpointXVal                    matlab.ui.control.NumericEditField
        EndpointXValEditFieldLabel      matlab.ui.control.Label
        StartButton                     matlab.ui.control.Button
        ArmJointAngle                   matlab.ui.control.Slider
        ArmJointAngleTheta2SliderLabel  matlab.ui.control.Label
        BaseJointAngle                  matlab.ui.control.Slider
        BaseJointAngleTheta1SliderLabel  matlab.ui.control.Label
        manipulatorPlot                 matlab.ui.control.UIAxes
    end

    
    methods (Access = public)
        
        function RobotPlot = RobotPlot(app, Link1Length, Link2Length, theta1, theta2)
            PosExtract=[0; 0; 0; 1];
            
            % Base to Arm
            p12=[Link1Length*cos(theta1); Link1Length*sin(theta1); 0];
            Rot12=[cos(theta1) -sin(theta1)   0
                        sin(theta1)    cos(theta1)    0
                        0   0   1];
            T12=HomogeneousTransform(Rot12, p12, 1);
            
            % Arm to Endpoint
            p23=[Link2Length*cos(theta2); Link2Length*sin(theta2); 0];
            Rot23=[cos(theta2) -sin(theta2)   0
                        sin(theta2)    cos(theta2)    0
                        0   0   1];
            T23=HomogeneousTransform(Rot23, p23, 1);
            
            %Base to Endpoint
            T13=T12*T23;
            
            % Position of Endpoint according to Base Coordinate
            p13=T13*PosExtract;
            app.EndpointXVal.Value=p13(1,1)
            app.EndpointYVal.Value=p13(2,1)
            app.EndpointZVal.Value=p13(3,1)
            
            % plot
            p = plot(app.manipulatorPlot, [PosExtract(1,1), p12(1,1), p13(1,1)],[PosExtract(2,1), p12(2,1), p13(2,1)],'-o','LineWidth',3);
            p.MarkerFaceColor = [1 0.5 0];
            p.MarkerSize = 8;
            p.MarkerEdgeColor = [1 0.5 0]
            app.manipulatorPlot.XLim = [-Link1Length-Link2Length Link1Length+Link2Length]
            app.manipulatorPlot.YLim = [-Link1Length-Link2Length Link1Length+Link2Length]
            app.manipulatorPlot.ZLim = [-Link1Length-Link2Length Link1Length+Link2Length]
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: BaseJointAngle, BaseJointAngle
        function BaseJointAngleValueChanged(app, event)
            Link1 = app.LengthofLink1.Value;
            Link2 = app.LengthofLink2.Value;
            theta1=event.Value;
            theta2=app.ArmJointAngle.Value;
            RobotPlot(app, Link1, Link2, theta1, theta2)
        end

        % Callback function: ArmJointAngle, ArmJointAngle
        function ArmJointAngleValueChanged(app, event)
            Link1 = app.LengthofLink1.Value;
            Link2 = app.LengthofLink2.Value;
            theta1=app.BaseJointAngle.Value;
            theta2=event.Value;
            RobotPlot(app, Link1, Link2, theta1, theta2)
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            Link1 = app.LengthofLink1.Value;
            Link2 = app.LengthofLink2.Value;
            theta1=app.BaseJointAngle.Value;
            theta2=app.ArmJointAngle.Value;
            RobotPlot(app, Link1, Link2, theta1, theta2)
        end

        % Value changed function: LengthofLink1
        function LengthofLink1ValueChanged(app, event)
            Link1 = event.Value;
            Link2 = app.LengthofLink2.Value;
            theta1=app.BaseJointAngle.Value;
            theta2=app.ArmJointAngle.Value;
            RobotPlot(app, Link1, Link2, theta1, theta2)
        end

        % Value changed function: LengthofLink2
        function LengthofLink2ValueChanged(app, event)
            Link1 = app.LengthofLink1.Value;
            Link2 = event.Value;
            theta1=app.BaseJointAngle.Value;
            theta2=app.ArmJointAngle.Value;
            RobotPlot(app, Link1, Link2, theta1, theta2)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.manipulatorPlot = uiaxes(app.UIFigure);
            title(app.manipulatorPlot, {'2-DoF Manipulator'; 'Forward Kinematics'})
            xlabel(app.manipulatorPlot, 'X')
            ylabel(app.manipulatorPlot, 'Y')
            zlabel(app.manipulatorPlot, 'Z')
            app.manipulatorPlot.XLim = [0 1];
            app.manipulatorPlot.YLim = [0 1];
            app.manipulatorPlot.ZLim = [0 1];
            app.BaseJointAngleTheta1SliderLabel = uilabel(app.UIFigure);
            app.BaseJointAngleTheta1SliderLabel.Text = 'Base Joint Angle (Theta 1)';
            app.BaseJointAngle = uislider(app.UIFigure);
            app.BaseJointAngle.Limits = [-3.14159265358979 3.14159265358979];
            app.BaseJointAngle.ValueChangedFcn = createCallbackFcn(app, @BaseJointAngleValueChanged, true);
            app.BaseJointAngle.ValueChangingFcn = createCallbackFcn(app, @BaseJointAngleValueChanged, true);
            app.ArmJointAngleTheta2SliderLabel = uilabel(app.UIFigure);
            app.ArmJointAngleTheta2SliderLabel.Text = 'Arm Joint Angle (Theta 2)';
            app.ArmJointAngle = uislider(app.UIFigure);
            app.ArmJointAngle.Limits = [-3.14159265358979 3.14159265358979];
            app.ArmJointAngle.ValueChangedFcn = createCallbackFcn(app, @ArmJointAngleValueChanged, true);
            app.ArmJointAngle.ValueChangingFcn = createCallbackFcn(app, @ArmJointAngleValueChanged, true);
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.EndpointXValEditFieldLabel = uilabel(app.UIFigure);
            app.EndpointXValEditFieldLabel.Text = 'Endpoint X Val';
            app.EndpointXVal = uieditfield(app.UIFigure, 'numeric');
            app.EndpointYValEditFieldLabel = uilabel(app.UIFigure);
            app.EndpointYValEditFieldLabel.Text = 'Endpoint Y Val';
            app.EndpointYVal = uieditfield(app.UIFigure, 'numeric');
            app.EndpointZValEditFieldLabel = uilabel(app.UIFigure);
            app.EndpointZValEditFieldLabel.Text = 'Endpoint Z Val';
            app.EndpointZVal = uieditfield(app.UIFigure, 'numeric');
            app.LengthofLink2EditFieldLabel = uilabel(app.UIFigure);
            app.LengthofLink2EditFieldLabel.Text = 'Length of Link2';
            app.LengthofLink2 = uieditfield(app.UIFigure, 'numeric');
            app.LengthofLink2.ValueChangedFcn = createCallbackFcn(app, @LengthofLink2ValueChanged, true);
            app.LengthofLink2.Value = 100;
            app.LengthofLink1EditFieldLabel = uilabel(app.UIFigure);
            app.LengthofLink1EditFieldLabel.Text = 'Length of Link1';
            app.LengthofLink1 = uieditfield(app.UIFigure, 'numeric');
            app.LengthofLink1.ValueChangedFcn = createCallbackFcn(app, @LengthofLink1ValueChanged, true);
            app.LengthofLink1.Value = 100;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TwoDofManipulatorFKCalculationUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end