classdef ThreeDofManipulatorT2IKCalculationUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Arm2JointAngleTheta3Label    matlab.ui.control.Label
        LengthofLink3Label           matlab.ui.control.Label
        LengthofLink1                matlab.ui.control.NumericEditField
        LengthofLink1EditFieldLabel  matlab.ui.control.Label
        LengthofLink2                matlab.ui.control.NumericEditField
        LengthofLink2EditFieldLabel  matlab.ui.control.Label
        LengthofLink3                matlab.ui.control.NumericEditField
        EndpointZVal                 matlab.ui.control.NumericEditField
        EndpointZValEditFieldLabel   matlab.ui.control.Label
        EndpointYVal                 matlab.ui.control.NumericEditField
        EndpointYValEditFieldLabel   matlab.ui.control.Label
        EndpointXVal                 matlab.ui.control.NumericEditField
        EndpointXValEditFieldLabel   matlab.ui.control.Label
        StartButton                  matlab.ui.control.Button
        Arm2JointAngle               matlab.ui.control.Slider
        Arm1JointAngle               matlab.ui.control.Slider
        Arm1JointAngleTheta2Label    matlab.ui.control.Label
        BaseJointAngle               matlab.ui.control.Slider
        BaseJointAngleTheta1SliderLabel  matlab.ui.control.Label
        manipulatorPlot              matlab.ui.control.UIAxes
    end

    
    methods (Access = public)
        
        function RobotPlot = RobotPlot(app, Link1Length, Link2Length, Link3Length, theta1, theta2, theta3)
            PosExtract=[0; 0; 0; 1];
            
            % Base to Arm1
            a0 = 0;
            alpha0 = 0;
            d1 = Link1Length;
            T01 = DHParameter(a0, alpha0, d1, theta1);
            
            % Arm1 to Arm2
            a1 = 0;
            alpha1 = pi/2;
            d2 = 0;
            T12 = DHParameter(a1, alpha1, d2, theta2);
            
            % Arm2 to Arm3
            a2 = Link2Length;
            alpha2 = 0;
            d3 = 0;
            T23 = DHParameter(a2, alpha2, d3, theta3);
            
            % Arm3 to Endpoint
            a3 = Link3Length;
            alpha3 = 0;
            d4 = 0;
            theta4 = 0;
            T3E = DHParameter(a3, alpha3, d4, theta4);
            
            % Base to Each Coordinate
            T02 = T01*T12;
            T03 = T01*T12*T23;
            T0E = T01*T12*T23*T3E;
            
            % Position of Each Coordinate according to Base Coordinate
            p01=T01*PosExtract;
            p02=T02*PosExtract;
            p03=T03*PosExtract;
            p0E=T0E*PosExtract;
            
            % Position of Endpoint according to Global Coordinate
            app.EndpointXVal.Value = p0E(1,1);
            app.EndpointYVal.Value = p0E(2,1);
            app.EndpointZVal.Value = p0E(3,1);
            
            % plot
            p = plot3(app.manipulatorPlot, [PosExtract(1,1), p02(1,1), p03(1,1), p0E(1,1)], [PosExtract(2,1), p02(2,1), p03(2,1), p0E(2,1)], [PosExtract(3,1), p02(3,1), p03(3,1), p0E(3,1)],'-o','LineWidth',3);
            p.MarkerFaceColor = [1 0.5 0];
            p.MarkerSize = 8;
            p.MarkerEdgeColor = [1 0.5 0];
            app.manipulatorPlot.XLim = [-Link1Length-Link2Length-Link3Length Link1Length+Link2Length+Link3Length];
            app.manipulatorPlot.YLim = [-Link1Length-Link2Length-Link3Length Link1Length+Link2Length+Link3Length];
            app.manipulatorPlot.ZLim = [-Link1Length-Link2Length-Link3Length Link1Length+Link2Length+Link3Length];
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: BaseJointAngle, BaseJointAngle
        function BaseJointAngleValueChanged(app, event)
            Link1 = app.LengthofLink1.Value;
            Link2 = app.LengthofLink2.Value;
            Link3 = app.LengthofLink3.Value;
            theta1=event.Value;
            theta2=app.Arm1JointAngle.Value;
            theta3=app.Arm2JointAngle.Value;
            RobotPlot(app, Link1, Link2, Link3, theta1, theta2, theta3)
        end

        % Callback function: Arm1JointAngle, Arm1JointAngle
        function ArmJointAngleValueChanged(app, event)
            Link1 = app.LengthofLink1.Value;
            Link2 = app.LengthofLink2.Value;
            Link3 = app.LengthofLink3.Value;
            theta1=app.BaseJointAngle.Value;
            theta2=event.Value;
            theta3=app.Arm2JointAngle.Value;
            RobotPlot(app, Link1, Link2, Link3, theta1, theta2, theta3)
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            Link1 = app.LengthofLink1.Value;
            Link2 = app.LengthofLink2.Value;
            Link3 = app.LengthofLink3.Value;
            theta1=app.BaseJointAngle.Value;
            theta2=app.Arm1JointAngle.Value;
            theta3=app.Arm2JointAngle.Value;
            RobotPlot(app, Link1, Link2, Link3, theta1, theta2, theta3)
        end

        % Value changed function: LengthofLink1
        function LengthofLink1ValueChanged(app, event)
            Link1 = event.Value;
            Link2 = app.LengthofLink2.Value;
            Link3 = app.LengthofLink3.Value;
            theta1=app.BaseJointAngle.Value;
            theta2=app.Arm1JointAngle.Value;
            theta3=app.Arm2JointAngle.Value;
            RobotPlot(app, Link1, Link2, Link3, theta1, theta2, theta3)
        end

        % Value changed function: LengthofLink2
        function LengthofLink2ValueChanged(app, event)
            Link1 = app.LengthofLink1.Value;
            Link2 = event.Value;
            Link3 = app.LengthofLink3.Value;
            theta1=app.BaseJointAngle.Value;
            theta2=app.Arm1JointAngle.Value;
            theta3=app.Arm2JointAngle.Value;
            RobotPlot(app, Link1, Link2, Link3, theta1, theta2, theta3)
        end

        % Value changed function: LengthofLink3
        function LengthofLink3ValueChanged(app, event)
            Link1 = app.LengthofLink1.Value;
            Link2 = app.LengthofLink2.Value;
            Link3 = event.Value;
            theta1=app.BaseJointAngle.Value;
            theta2=app.Arm1JointAngle.Value;
            theta3=app.Arm2JointAngle.Value;
            RobotPlot(app, Link1, Link2, Link3, theta1, theta2, theta3)
        end

        % Callback function: Arm2JointAngle, Arm2JointAngle
        function Arm2JointAngleValueChanging(app, event)
            Link1 = app.LengthofLink1.Value;
            Link2 = app.LengthofLink2.Value;
            Link3 = app.LengthofLink3.Value;
            theta1=app.BaseJointAngle.Value;
            theta2=app.Arm1JointAngle.Value;
            theta3=event.Value;
            RobotPlot(app, Link1, Link2, Link3, theta1, theta2, theta3)
        end

        % Value changed function: EndpointXVal
        function EndpointXValValueChanged(app, event)
            L1 = app.LengthofLink1.Value;
            L2 = app.LengthofLink2.Value;
            L3 = app.LengthofLink3.Value;
            x = app.EndpointXVal.Value;
            y = app.EndpointYVal.Value;
            z = app.EndpointZVal.Value;
            xn = sqrt(x^2 + y^2);
            yn = z - L1;
            theta1 = atan2(y, x);
            theta3 = -acos((xn^2+yn^2-L2^2-L3^2)/(2*L2*L3));
            theta2 = atan2(yn, xn) - atan2(L3*sin(theta3), L2+L3*cos(theta3));
            app.BaseJointAngle.Value = theta1;
            app.Arm1JointAngle.Value = theta2;
            app.Arm2JointAngle.Value = theta3;
            RobotPlot(app, L1, L2, L3, theta1, theta2, theta3)
        end

        % Value changed function: EndpointYVal
        function EndpointYValValueChanged(app, event)
            L1 = app.LengthofLink1.Value;
            L2 = app.LengthofLink2.Value;
            L3 = app.LengthofLink3.Value;
            x = app.EndpointXVal.Value;
            y = app.EndpointYVal.Value;
            z = app.EndpointZVal.Value;
            xn = sqrt(x^2 + y^2);
            yn = z - L1;
            theta1 = atan2(y, x);
            theta3 = -acos((xn^2+yn^2-L2^2-L3^2)/(2*L2*L3));
            theta2 = atan2(yn, xn) - atan2(L3*sin(theta3), L2+L3*cos(theta3));
            app.BaseJointAngle.Value = theta1;
            app.Arm1JointAngle.Value = theta2;
            app.Arm2JointAngle.Value = theta3;
            RobotPlot(app, L1, L2, L3, theta1, theta2, theta3)
        end

        % Value changed function: EndpointZVal
        function EndpointZValValueChanged(app, event)
            L1 = app.LengthofLink1.Value;
            L2 = app.LengthofLink2.Value;
            L3 = app.LengthofLink3.Value;
            x = app.EndpointXVal.Value;
            y = app.EndpointYVal.Value;
            z = app.EndpointZVal.Value;
            xn = sqrt(x^2 + y^2);
            yn = z - L1;
            theta1 = atan2(y, x);
            theta3 = -acos((xn^2+yn^2-L2^2-L3^2)/(2*L2*L3));
            theta2 = atan2(yn, xn) - atan2(L3*sin(theta3), L2+L3*cos(theta3));
            app.BaseJointAngle.Value = theta1;
            app.Arm1JointAngle.Value = theta2;
            app.Arm2JointAngle.Value = theta3;
            RobotPlot(app, L1, L2, L3, theta1, theta2, theta3)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.manipulatorPlot = uiaxes(app.UIFigure);
            title(app.manipulatorPlot, {'3-DoF Manipulator'; 'Forward Kinematics'})
            xlabel(app.manipulatorPlot, 'X')
            ylabel(app.manipulatorPlot, 'Y')
            zlabel(app.manipulatorPlot, 'Z')
            app.manipulatorPlot.XLim = [0 1];
            app.manipulatorPlot.YLim = [0 1];
            app.manipulatorPlot.ZLim = [0 1];
            app.BaseJointAngleTheta1SliderLabel = uilabel(app.UIFigure);
            app.BaseJointAngleTheta1SliderLabel.Text = 'Base Joint Angle (Theta 1)';
            app.BaseJointAngle = uislider(app.UIFigure);
            app.BaseJointAngle.Limits = [-3.14159265358979 3.14159265358979];
            app.BaseJointAngle.ValueChangedFcn = createCallbackFcn(app, @BaseJointAngleValueChanged, true);
            app.BaseJointAngle.ValueChangingFcn = createCallbackFcn(app, @BaseJointAngleValueChanged, true);
            app.Arm1JointAngleTheta2Label = uilabel(app.UIFigure);
            app.Arm1JointAngleTheta2Label.Text = 'Arm1 Joint Angle (Theta 2)';
            app.Arm1JointAngle = uislider(app.UIFigure);
            app.Arm1JointAngle.Limits = [-3.14159265358979 3.14159265358979];
            app.Arm1JointAngle.ValueChangedFcn = createCallbackFcn(app, @ArmJointAngleValueChanged, true);
            app.Arm1JointAngle.ValueChangingFcn = createCallbackFcn(app, @ArmJointAngleValueChanged, true);
            app.Arm2JointAngle = uislider(app.UIFigure);
            app.Arm2JointAngle.Limits = [-3.14159265358979 3.14159265358979];
            app.Arm2JointAngle.ValueChangedFcn = createCallbackFcn(app, @Arm2JointAngleValueChanging, true);
            app.Arm2JointAngle.ValueChangingFcn = createCallbackFcn(app, @Arm2JointAngleValueChanging, true);
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.EndpointXValEditFieldLabel = uilabel(app.UIFigure);
            app.EndpointXValEditFieldLabel.Text = 'Endpoint X Val';
            app.EndpointXVal = uieditfield(app.UIFigure, 'numeric');
            app.EndpointXVal.ValueChangedFcn = createCallbackFcn(app, @EndpointXValValueChanged, true);
            app.EndpointYValEditFieldLabel = uilabel(app.UIFigure);
            app.EndpointYValEditFieldLabel.Text = 'Endpoint Y Val';
            app.EndpointYVal = uieditfield(app.UIFigure, 'numeric');
            app.EndpointYVal.ValueChangedFcn = createCallbackFcn(app, @EndpointYValValueChanged, true);
            app.EndpointZValEditFieldLabel = uilabel(app.UIFigure);
            app.EndpointZValEditFieldLabel.Text = 'Endpoint Z Val';
            app.EndpointZVal = uieditfield(app.UIFigure, 'numeric');
            app.EndpointZVal.ValueChangedFcn = createCallbackFcn(app, @EndpointZValValueChanged, true);
            app.LengthofLink3 = uieditfield(app.UIFigure, 'numeric');
            app.LengthofLink3.ValueChangedFcn = createCallbackFcn(app, @LengthofLink3ValueChanged, true);
            app.LengthofLink3.Value = 200;
            app.LengthofLink2EditFieldLabel = uilabel(app.UIFigure);
            app.LengthofLink2EditFieldLabel.Text = 'Length of Link2';
            app.LengthofLink2 = uieditfield(app.UIFigure, 'numeric');
            app.LengthofLink2.ValueChangedFcn = createCallbackFcn(app, @LengthofLink2ValueChanged, true);
            app.LengthofLink2.Value = 200;
            app.LengthofLink1EditFieldLabel = uilabel(app.UIFigure);
            app.LengthofLink1EditFieldLabel.Text = 'Length of Link1';
            app.LengthofLink1 = uieditfield(app.UIFigure, 'numeric');
            app.LengthofLink1.ValueChangedFcn = createCallbackFcn(app, @LengthofLink1ValueChanged, true);
            app.LengthofLink1.Value = 200;
            app.LengthofLink3Label = uilabel(app.UIFigure);
            app.LengthofLink3Label.Text = 'Length of Link3';
            app.Arm2JointAngleTheta3Label = uilabel(app.UIFigure);
            app.Arm2JointAngleTheta3Label.Text = 'Arm2 Joint Angle (Theta 3)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ThreeDofManipulatorT2IKCalculationUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end