classdef ThreeDofManipulatorFKCalculationUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Arm2JointAngleTheta3Label    matlab.ui.control.Label
        LengthofLink3Label           matlab.ui.control.Label
        LengthofLink1                matlab.ui.control.NumericEditField
        LengthofLink1EditFieldLabel  matlab.ui.control.Label
        LengthofLink2                matlab.ui.control.NumericEditField
        LengthofLink2EditFieldLabel  matlab.ui.control.Label
        LengthofLink3                matlab.ui.control.NumericEditField
        EndpointZVal                 matlab.ui.control.NumericEditField
        EndpointZValEditFieldLabel   matlab.ui.control.Label
        EndpointYVal                 matlab.ui.control.NumericEditField
        EndpointYValEditFieldLabel   matlab.ui.control.Label
        EndpointXVal                 matlab.ui.control.NumericEditField
        EndpointXValEditFieldLabel   matlab.ui.control.Label
        StartButton                  matlab.ui.control.Button
        Arm2JointAngle               matlab.ui.control.Slider
        Arm1JointAngle               matlab.ui.control.Slider
        Arm1JointAngleTheta2Label    matlab.ui.control.Label
        BaseJointAngle               matlab.ui.control.Slider
        BaseJointAngleTheta1SliderLabel  matlab.ui.control.Label
        manipulatorPlot              matlab.ui.control.UIAxes
    end

    
    methods (Access = public)
        
        function RobotPlot = RobotPlot(app, Link1Length, Link2Length, Link3Length, theta1, theta2, theta3)
            PosExtract=[0; 0; 0; 1];
            
            % Global to Base
            angleg1=pi/2;
            pg1=[0;0;0];
            Rotg1=[1  0   0
            0   cos(angleg1)  -sin(angleg1)
            0   sin(angleg1)  cos(angleg1)];
            Tg1=HomogeneousTransform(Rotg1, pg1, 1);
            
            % Base to Arm1
            angle12=theta1;
            p12=[0; Link1Length; 0];
            Rot12=[cos(angle12) 0   sin(angle12)
            0   1   0
            -sin(angle12)   0   cos(angle12)];
            T12=HomogeneousTransform(Rot12, p12, 1);
            
            % Arm1 to Arm2
            angle23=theta2;
            p23=[Link2Length*cos(theta2); Link2Length*sin(theta2); 0];
            Rot23=[cos(angle23) -sin(angle23)   0
            sin(angle23)    cos(angle23)    0
            0   0   1];
            T23=HomogeneousTransform(Rot23, p23, 1);
            
            % Arm2 to Endpoint
            angle34=theta3;
            p34=[Link3Length*cos(theta3); Link3Length*sin(theta3); 0];
            Rot34=[cos(angle34) -sin(angle34)   0
            sin(angle34)    cos(angle34)    0
            0   0   1];
            T34=HomogeneousTransform(Rot34, p34, 1);
            
            % Transformation matrix of Global Coordinate to each joint
            Tg2=Tg1*T12;
            Tg3=Tg2*T23;
            Tg4=Tg3*T34;
            
            % Position of each joint according to Global Coordinate
            pg2=Tg2*PosExtract;
            pg3=Tg3*PosExtract;
            pg4=Tg4*PosExtract;
            
            % Position of Endpoint according to Global Coordinate
            app.EndpointXVal.Value=pg4(1,1)
            app.EndpointYVal.Value=pg4(2,1)
            app.EndpointZVal.Value=pg4(3,1)
            
            % plot
            p = plot3(app.manipulatorPlot, [PosExtract(1,1), pg2(1,1), pg3(1,1), pg4(1,1)], [PosExtract(2,1), pg2(2,1), pg3(2,1), pg4(2,1)], [PosExtract(3,1), pg2(3,1), pg3(3,1), pg4(3,1)],'-o','LineWidth',3);
            p.MarkerFaceColor = [1 0.5 0];
            p.MarkerSize = 8;
            p.MarkerEdgeColor = [1 0.5 0]
            app.manipulatorPlot.XLim = [-Link1Length-Link2Length-Link3Length Link1Length+Link2Length+Link3Length]
            app.manipulatorPlot.YLim = [-Link1Length-Link2Length-Link3Length Link1Length+Link2Length+Link3Length]
            app.manipulatorPlot.ZLim = [-Link1Length-Link2Length-Link3Length Link1Length+Link2Length+Link3Length]
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: BaseJointAngle, BaseJointAngle
        function BaseJointAngleValueChanged(app, event)
            Link1 = app.LengthofLink1.Value;
            Link2 = app.LengthofLink2.Value;
            Link3 = app.LengthofLink3.Value;
            theta1=event.Value;
            theta2=app.Arm1JointAngle.Value;
            theta3=app.Arm2JointAngle.Value;
            RobotPlot(app, Link1, Link2, Link3, theta1, theta2, theta3)
        end

        % Callback function: Arm1JointAngle, Arm1JointAngle
        function ArmJointAngleValueChanged(app, event)
            Link1 = app.LengthofLink1.Value;
            Link2 = app.LengthofLink2.Value;
            Link3 = app.LengthofLink3.Value;
            theta1=app.BaseJointAngle.Value;
            theta2=event.Value;
            theta3=app.Arm2JointAngle.Value;
            RobotPlot(app, Link1, Link2, Link3, theta1, theta2, theta3)
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            Link1 = app.LengthofLink1.Value;
            Link2 = app.LengthofLink2.Value;
            Link3 = app.LengthofLink3.Value;
            theta1=app.BaseJointAngle.Value;
            theta2=app.Arm1JointAngle.Value;
            theta3=app.Arm2JointAngle.Value;
            RobotPlot(app, Link1, Link2, Link3, theta1, theta2, theta3)
        end

        % Value changed function: LengthofLink1
        function LengthofLink1ValueChanged(app, event)
            Link1 = event.Value;
            Link2 = app.LengthofLink2.Value;
            Link3 = app.LengthofLink3.Value;
            theta1=app.BaseJointAngle.Value;
            theta2=app.Arm1JointAngle.Value;
            theta3=app.Arm2JointAngle.Value;
            RobotPlot(app, Link1, Link2, Link3, theta1, theta2, theta3)
        end

        % Value changed function: LengthofLink2
        function LengthofLink2ValueChanged(app, event)
            Link1 = app.LengthofLink1.Value;
            Link2 = event.Value;
            Link3 = app.LengthofLink3.Value;
            theta1=app.BaseJointAngle.Value;
            theta2=app.Arm1JointAngle.Value;
            theta3=app.Arm2JointAngle.Value;
            RobotPlot(app, Link1, Link2, Link3, theta1, theta2, theta3)
        end

        % Value changed function: LengthofLink3
        function LengthofLink3ValueChanged(app, event)
            value = app.LengthofLink3.Value;
            Link1 = app.LengthofLink1.Value;
            Link2 = app.LengthofLink2.Value;
            Link3 = event.Value;
            theta1=app.BaseJointAngle.Value;
            theta2=app.Arm1JointAngle.Value;
            theta3=app.Arm2JointAngle.Value;
            RobotPlot(app, Link1, Link2, Link3, theta1, theta2, theta3)
        end

        % Callback function: Arm2JointAngle, Arm2JointAngle
        function Arm2JointAngleValueChanging(app, event)
            Link1 = app.LengthofLink1.Value;
            Link2 = app.LengthofLink2.Value;
            Link3 = app.LengthofLink3.Value;
            theta1=app.BaseJointAngle.Value;
            theta2=app.Arm1JointAngle.Value;
            theta3=event.Value;
            RobotPlot(app, Link1, Link2, Link3, theta1, theta2, theta3)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.manipulatorPlot = uiaxes(app.UIFigure);
            title(app.manipulatorPlot, {'3-DoF Manipulator'; 'Forward Kinematics'})
            xlabel(app.manipulatorPlot, 'X')
            ylabel(app.manipulatorPlot, 'Y')
            zlabel(app.manipulatorPlot, 'Z')
            app.manipulatorPlot.XLim = [0 1];
            app.manipulatorPlot.YLim = [0 1];
            app.manipulatorPlot.ZLim = [0 1];
            app.BaseJointAngleTheta1SliderLabel = uilabel(app.UIFigure);
            app.BaseJointAngleTheta1SliderLabel.Text = 'Base Joint Angle (Theta 1)';
            app.BaseJointAngle = uislider(app.UIFigure);
            app.BaseJointAngle.Limits = [-3.14159265358979 3.14159265358979];
            app.BaseJointAngle.ValueChangedFcn = createCallbackFcn(app, @BaseJointAngleValueChanged, true);
            app.BaseJointAngle.ValueChangingFcn = createCallbackFcn(app, @BaseJointAngleValueChanged, true);
            app.Arm1JointAngleTheta2Label = uilabel(app.UIFigure);
            app.Arm1JointAngleTheta2Label.Text = 'Arm1 Joint Angle (Theta 2)';
            app.Arm1JointAngle = uislider(app.UIFigure);
            app.Arm1JointAngle.Limits = [-3.14159265358979 3.14159265358979];
            app.Arm1JointAngle.ValueChangedFcn = createCallbackFcn(app, @ArmJointAngleValueChanged, true);
            app.Arm1JointAngle.ValueChangingFcn = createCallbackFcn(app, @ArmJointAngleValueChanged, true);
            app.Arm2JointAngle = uislider(app.UIFigure);
            app.Arm2JointAngle.Limits = [-3.14159265358979 3.14159265358979];
            app.Arm2JointAngle.ValueChangedFcn = createCallbackFcn(app, @Arm2JointAngleValueChanging, true);
            app.Arm2JointAngle.ValueChangingFcn = createCallbackFcn(app, @Arm2JointAngleValueChanging, true);
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.EndpointXValEditFieldLabel = uilabel(app.UIFigure);
            app.EndpointXValEditFieldLabel.Text = 'Endpoint X Val';
            app.EndpointXVal = uieditfield(app.UIFigure, 'numeric');
            app.EndpointYValEditFieldLabel = uilabel(app.UIFigure);
            app.EndpointYValEditFieldLabel.Text = 'Endpoint Y Val';
            app.EndpointYVal = uieditfield(app.UIFigure, 'numeric');
            app.EndpointZValEditFieldLabel = uilabel(app.UIFigure);
            app.EndpointZValEditFieldLabel.Text = 'Endpoint Z Val';
            app.EndpointZVal = uieditfield(app.UIFigure, 'numeric');
            app.LengthofLink3 = uieditfield(app.UIFigure, 'numeric');
            app.LengthofLink3.ValueChangedFcn = createCallbackFcn(app, @LengthofLink3ValueChanged, true);
            app.LengthofLink3.Value = 100;
            app.LengthofLink2EditFieldLabel = uilabel(app.UIFigure);
            app.LengthofLink2EditFieldLabel.Text = 'Length of Link2';
            app.LengthofLink2 = uieditfield(app.UIFigure, 'numeric');
            app.LengthofLink2.ValueChangedFcn = createCallbackFcn(app, @LengthofLink2ValueChanged, true);
            app.LengthofLink2.Value = 100;
            app.LengthofLink1EditFieldLabel = uilabel(app.UIFigure);
            app.LengthofLink1EditFieldLabel.Text = 'Length of Link1';
            app.LengthofLink1 = uieditfield(app.UIFigure, 'numeric');
            app.LengthofLink1.ValueChangedFcn = createCallbackFcn(app, @LengthofLink1ValueChanged, true);
            app.LengthofLink1.Value = 100;
            app.LengthofLink3Label = uilabel(app.UIFigure);
            app.LengthofLink3Label.Text = 'Length of Link3';
            app.Arm2JointAngleTheta3Label = uilabel(app.UIFigure);
            app.Arm2JointAngleTheta3Label.Text = 'Arm2 Joint Angle (Theta 3)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ThreeDofManipulatorFKCalculationUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end